classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ListBox             matlab.ui.control.ListBox
        ListBoxLabel        matlab.ui.control.Label
        AlarmLamp           matlab.ui.control.Lamp
        AlarmLampLabel      matlab.ui.control.Label
        OpenDocumentButton  matlab.ui.control.Button
        StopButton          matlab.ui.control.Button
        CameraLabel         matlab.ui.control.Label
        StartButton         matlab.ui.control.Button
        UIAxes_2            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        label
        cam
        scores  
        stopier % Description
        
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
        
            app.cam = webcam;
            
            trainedmodel = load("G:\FYP\r2plus1d-V2.mat");
            R2D = trainedmodel.data.r2plus1d;
            classifyInterval = 10;
            sequenceLength = R2D.InputSize(4);
            numFrames = 0;
            text = "";
            Alable = {'stand'};
            app.label = "";
            TEXT = {};
            n = 1;

            app.stopier = 0;

    while 1

        pause(1e-4);

        if app.stopier

            break

        end

      frame = snapshot(app.cam);

      numFrames = numFrames + 1;
      R2D = updateSequence(R2D,frame);

    if mod(numFrames, classifyInterval) == 0 && numFrames >= sequenceLength
        
        [app.label,app.scores] = classifySequence(R2D);
        app.AlarmLamp.Color = [0.00,1.00,0.00]; 
        
        if ~isempty(app.label)
            text = string(app.label) + "; " + num2str(max(app.scores), "%0.2f");
        end

    end

    frame = insertText(frame,[30,30],text,'FontSize',18);
    tf = strcmp(string(app.label), Alable);

    if sum(tf(:)) ~= 0     
            filename = sprintf('G:\\FYP\\Store\\%s_%s.jpg', string(datetime,'yyyy-MM-dd-HH-mm-ss'),string(app.label));
            Text = sprintf('%s_%s',string(datetime,'yyyy-MM-dd-HH-mm-ss'),string(app.label));
            TEXT{n} = Text;
            imwrite(frame,filename);% 保存
            app.AlarmLamp.Color = [1.00,0.00,0.00];
            n = n+1;
    end
    app.ListBox.Items = TEXT;
     
     imshow(frame,'Parent',app.UIAxes_2);

    end


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
           app.stopier = 1;
        end

        % Button pushed function: OpenDocumentButton
        function OpenDocumentButtonPushed(app, event)
            winopen('G:\FYP\store');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           
        YN = uiconfirm(app.UIFigure,'Do you want to close the app?', 'Close request');
                
                if strcmpi(YN,'OK')

                       delete(app)

                end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tag = 'Start';
            app.StartButton.Text = 'Start';
            app.CameraLabel = uilabel(app.UIFigure);
            app.CameraLabel.Text = 'Camera';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.OpenDocumentButton = uibutton(app.UIFigure, 'push');
            app.OpenDocumentButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDocumentButtonPushed, true);
            app.OpenDocumentButton.Text = 'OpenDocument';
            app.AlarmLampLabel = uilabel(app.UIFigure);
            app.AlarmLampLabel.Text = 'Alarm';
            app.AlarmLamp = uilamp(app.UIFigure);
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end